classdef recorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RecorderUIFigure                matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        VoiceRecorderTab                matlab.ui.container.Tab
        RecordButton                    matlab.ui.control.Button
        PreviewButton                   matlab.ui.control.Button
        SpeechProjectLabel              matlab.ui.control.Label
        ProgrammedbyAliTabaraeiLabel    matlab.ui.control.Label
        SelectdirectorytosaveaudioSwitchLabel  matlab.ui.control.Label
        selectedDirectory               matlab.ui.control.Switch
        SelectlabelforaudioSwitchLabel  matlab.ui.control.Label
        selectedLabel                   matlab.ui.control.Switch
        Panel                           matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        ForexampleCUsersusernameDesktopSpeechProjectLabel  matlab.ui.control.Label
        PleaseinsertrootofprojectLabel_2  matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        TrainTab                        matlab.ui.container.Tab
        CreateModelButton               matlab.ui.control.Button
        table                           matlab.ui.control.Table
        TrainingDatasetTableLabel       matlab.ui.control.Label
        ModelConfirm                    matlab.ui.control.Label
        UseTab                          matlab.ui.container.Tab
        PredictButton                   matlab.ui.control.Button
        trainTable                      matlab.ui.control.Table
        useTable                        matlab.ui.control.Table
        TrainDatasetLabel               matlab.ui.control.Label
        UseDatasetLabel                 matlab.ui.control.Label
        trainAccuracy                   matlab.ui.control.Label
        useAccuracy                     matlab.ui.control.Label
    end


    properties (Access = private)
        % Record Audio
        audio = audiorecorder(11025, 8, 1);
        recording = false;
        
        % file path
        fileName = '';
        
        % root address
        root = '';
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % hide label
            app.ModelConfirm.Visible = 'off';
            app.trainAccuracy.Visible = 'off';
            app.useAccuracy.Visible = 'off';
        end

        % Button pushed function: CreateModelButton
        function CreateModel(app, event)
            % create model
            createDataSet('Train', app.root);
            createSvmModel(app.root);
            
            % modify table data
            load([app.root, '\DataSet\trainFeatures.mat']);
            app.table.Data = [(1:size(features,1))', features];
            
            % visible label
            app.ModelConfirm.Visible = 'on';
        end

        % Button pushed function: PredictButton
        function predictButton(app, event)
            createDataSet('Train', app.root);
            createDataSet('Use', app.root);
            trainData = [ getRealLabels('trainFeatures', app.root), getPredictedLabels('trainFeatures', app.root) ];
            useData = [ getRealLabels('useFeatures', app.root), getPredictedLabels('useFeatures', app.root) ];
            app.trainTable.Data = trainData;
            app.useTable.Data = useData;
            
            % accuracy
            app.trainAccuracy.Text = ['Accuracy = ', num2str((sum(trainData(:,1) == trainData(:,2))/size(trainData,1))*100), '%'];
            app.useAccuracy.Text = ['Accuracy = ', num2str((sum(useData(:,1) == useData(:,2))/size(useData,1))*100), '%'];
            app.trainAccuracy.Visible = 'on';
            app.useAccuracy.Visible = 'on';
        end

        % Button pushed function: PreviewButton
        function previewButton(app, event)
            a = audioread(app.fileName);
            sound(a, 11025);
        end

        % Button pushed function: RecordButton
        function recordButton(app, event)
            % --------------------------------- Record ---------------------------------
            if app.recording == false
                % start recording
                record(app.audio);
                app.recording = true;
                
                % visualize
                app.RecordButton.Text = 'Stop';
                app.RecordButton.BackgroundColor = [0.5, 0.5, 0.5];
                
            % ---------------------------------- stop ----------------------------------
            else
                % stop recording
                stop(app.audio);
                speechRecord = getaudiodata(app.audio,'double');
                
                % get index automatically
                if strcmp(app.selectedDirectory.Value, 'Train')
                    index = autoIndex('Train', app.selectedLabel.Value, app.root);
                else
                    index = autoIndex('Use', app.selectedLabel.Value, app.root);
                end
                
                % store audio
                app.fileName = [app.root, '\Recordings\', app.selectedDirectory.Value, '\', app.selectedLabel.Value, ' ', index, '.wav'];
                audiowrite(app.fileName, speechRecord, 11025);
                % featureDetection(speechRecord, app.label);
                
                % visualize
                app.recording = false;
                app.RecordButton.Text = 'Record';
                app.RecordButton.BackgroundColor = [1.0, 0.0, 0.0];
                
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % take root address from user
            app.root = app.EditField.Value;
            app.Panel.Visible = false;
            
            % modify table data
            load([app.root, '\DataSet\trainFeatures.mat']);
            app.table.Data = [(1:size(features,1))', features];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RecorderUIFigure = uifigure('Visible', 'off');
            app.RecorderUIFigure.Name = 'Recorder';
            app.TabGroup = uitabgroup(app.RecorderUIFigure);
            app.VoiceRecorderTab = uitab(app.TabGroup);
            app.VoiceRecorderTab.Title = 'Voice Recorder';
            app.RecordButton = uibutton(app.VoiceRecorderTab, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @recordButton, true);
            app.RecordButton.Text = 'Record';
            app.PreviewButton = uibutton(app.VoiceRecorderTab, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @previewButton, true);
            app.PreviewButton.Text = 'Preview';
            app.SpeechProjectLabel = uilabel(app.VoiceRecorderTab);
            app.SpeechProjectLabel.Text = 'Speech Project';
            app.ProgrammedbyAliTabaraeiLabel = uilabel(app.VoiceRecorderTab);
            app.ProgrammedbyAliTabaraeiLabel.Text = 'Programmed by Ali Tabaraei';
            app.SelectdirectorytosaveaudioSwitchLabel = uilabel(app.VoiceRecorderTab);
            app.SelectdirectorytosaveaudioSwitchLabel.Text = 'Select directory to save audio';
            app.selectedDirectory = uiswitch(app.VoiceRecorderTab, 'slider');
            app.selectedDirectory.Items = {'Train', 'Use'};
            app.selectedDirectory.Value = 'Train';
            app.SelectlabelforaudioSwitchLabel = uilabel(app.VoiceRecorderTab);
            app.SelectlabelforaudioSwitchLabel.Text = 'Select label for audio';
            app.selectedLabel = uiswitch(app.VoiceRecorderTab, 'slider');
            app.selectedLabel.Items = {'Hi', 'Bye'};
            app.selectedLabel.Value = 'Hi';
            app.Panel = uipanel(app.VoiceRecorderTab);
            app.Panel.Title = 'Panel';
            app.EditField = uieditfield(app.Panel, 'text');
            app.ForexampleCUsersusernameDesktopSpeechProjectLabel = uilabel(app.Panel);
            app.ForexampleCUsersusernameDesktopSpeechProjectLabel.Text = 'For example, C:\Users\username\Desktop\Speech-Project';
            app.PleaseinsertrootofprojectLabel_2 = uilabel(app.Panel);
            app.PleaseinsertrootofprojectLabel_2.Text = 'Please insert root of project';
            app.NextButton = uibutton(app.Panel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.CreateModelButton = uibutton(app.TrainTab, 'push');
            app.CreateModelButton.ButtonPushedFcn = createCallbackFcn(app, @CreateModel, true);
            app.CreateModelButton.Text = 'Create Model';
            app.table = uitable(app.TrainTab);
            app.table.ColumnName = {'#'; 'Min Energy'; 'Max Energy'; 'Mean Energy'; 'Var Energy'; 'Min ZeroCrossing'; 'Max ZeroCrossing'; 'Mean ZeroCrossing'; 'Var ZeroCrossing'; 'Min MFCC (1)'; 'Min MFCC (2)'; 'Min MFCC (3)'; 'Min MFCC (4)'; 'Min MFCC (5)'; 'Min MFCC (6)'; 'Min MFCC (7)'; 'Min MFCC (8)'; 'Min MFCC (9)'; 'Min MFCC (10)'; 'Min MFCC (11)'; 'Min MFCC (12)'; 'Min MFCC (13)'; 'Max MFCC (1)'; 'Max MFCC (2)'; 'Max MFCC (3)'; 'Max MFCC (4)'; 'Max MFCC (5)'; 'Max MFCC (6)'; 'Max MFCC (7)'; 'Max MFCC (8)'; 'Max MFCC (9)'; 'Max MFCC (10)'; 'Max MFCC (11)'; 'Max MFCC (12)'; 'Max MFCC (13)'; 'Mean MFCC (1)'; 'Mean MFCC (2)'; 'Mean MFCC (3)'};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
            app.table.RowName = {};
            app.TrainingDatasetTableLabel = uilabel(app.TrainTab);
            app.TrainingDatasetTableLabel.Text = 'Training Dataset Table';
            app.ModelConfirm = uilabel(app.TrainTab);
            app.ModelConfirm.Text = 'Model Created';
            app.UseTab = uitab(app.TabGroup);
            app.UseTab.Title = 'Use';
            app.PredictButton = uibutton(app.UseTab, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @predictButton, true);
            app.PredictButton.Text = 'Predict';
            app.trainTable = uitable(app.UseTab);
            app.trainTable.ColumnName = {'Real Label'; 'Predicted Label'};
            app.trainTable.RowName = {};
            app.useTable = uitable(app.UseTab);
            app.useTable.ColumnName = {'Real Label'; 'Predicted Label'};
            app.useTable.RowName = {};
            app.TrainDatasetLabel = uilabel(app.UseTab);
            app.TrainDatasetLabel.Text = 'Train Dataset';
            app.UseDatasetLabel = uilabel(app.UseTab);
            app.UseDatasetLabel.Text = 'Use Dataset';
            app.trainAccuracy = uilabel(app.UseTab);
            app.trainAccuracy.Text = 'Train Accuracy';
            app.useAccuracy = uilabel(app.UseTab);
            app.useAccuracy.Text = 'Use Accuracy';
            % Show the figure after all components are created
            app.RecorderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recorder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RecorderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RecorderUIFigure)
        end
    end
end